classdef MainMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        tab1                            matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        PCM                             matlab.ui.container.Tab
        GenerateButton_2                matlab.ui.control.Button
        SignalRangeLabel_2              matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        toLabel_2                       matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        RangeGapLabel_2                 matlab.ui.control.Label
        EditField_11                    matlab.ui.control.NumericEditField
        DropDown_2                      matlab.ui.control.DropDown
        FunctionLabel_2                 matlab.ui.control.Label
        EditField_12                    matlab.ui.control.NumericEditField
        AmplitudeLabel_2                matlab.ui.control.Label
        PulseCodeModulationPCMLabel     matlab.ui.control.Label
        ComparewithDMButton             matlab.ui.control.Button
        CompareButton_2                 matlab.ui.control.Button
        FrequencyLabel_2                matlab.ui.control.Label
        EditField_13                    matlab.ui.control.NumericEditField
        SamplingRateLabel_2             matlab.ui.control.Label
        EditField_14                    matlab.ui.control.NumericEditField
        DeltaLabel_2                    matlab.ui.control.Label
        EditField_15                    matlab.ui.control.NumericEditField
        UIAxes_2                        matlab.ui.control.UIAxes
        Button_2                        matlab.ui.control.Button
        DM                              matlab.ui.container.Tab
        DeltaModulationDMLabel          matlab.ui.control.Label
        DeltaLabel                      matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        SignalRangeLabel                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        RangeGapLabel                   matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        FunctionLabel                   matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        EditField_5                     matlab.ui.control.NumericEditField
        AmplitudeLabel                  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
        ComparewithPCMButton            matlab.ui.control.Button
        FrequencyLabel                  matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        SamplingRateLabel               matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        CompareButton                   matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        tab2                            matlab.ui.container.Tab
        TabGroup_3                      matlab.ui.container.TabGroup
        AMITab                          matlab.ui.container.Tab
        AlternateMarkInversionAMILabel  matlab.ui.control.Label
        PseudoternaryTab                matlab.ui.container.Tab
        PseudoternaryLabel              matlab.ui.control.Label
        NRZLTab                         matlab.ui.container.Tab
        NRZLLabel                       matlab.ui.control.Label
        NRZITab                         matlab.ui.container.Tab
        NRZILabel                       matlab.ui.control.Label
        ManchesterTab                   matlab.ui.container.Tab
        ManchesterLabel                 matlab.ui.control.Label
        DifferentialManchesterTab       matlab.ui.container.Tab
        DifferentialManchesterLabel     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GenerateButton_3                matlab.ui.control.Button
        BitsLabel                       matlab.ui.control.Label
        a1                              matlab.ui.control.NumericEditField
        BitrateLabel                    matlab.ui.control.Label
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
        SamplingRateLabel_3             matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Button_4                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        LastbitLabel                    matlab.ui.control.Label
        a2                              matlab.ui.control.NumericEditField
        a3                              matlab.ui.control.NumericEditField
        a4                              matlab.ui.control.NumericEditField
        a5                              matlab.ui.control.NumericEditField
        a6                              matlab.ui.control.NumericEditField
        a7                              matlab.ui.control.NumericEditField
        a8                              matlab.ui.control.NumericEditField
        DropDown_3                      matlab.ui.control.DropDown
        UIAxes_3                        matlab.ui.control.UIAxes
        Button_3                        matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
    end

    properties (Access = private)
        OriginalAxesPosition  % Stores original UIAxes position
        IsExpanded = false    % Tracks whether UIAxes is expanded
    end

    methods (Access = private)
    
        function [x0] = performDeltaModulation(~, x, delta)
            x0 = zeros(1, length(x));
            x0(1) = 0;
            for i = 2:length(x)
                if x(i) > x0(i-1)
                    x0(i) = x0(i-1) + delta;
                else
                    x0(i) = x0(i-1) - delta;
                end
            end
        end

        function [x] = performAMI(~, bits, n, lastbit)
            x = zeros(1, length(bits)*n);
            for i = 1:length(bits)
                if bits(i) == 1
                    x((i-1)*n+1:i*n) = -lastbit;
                    lastbit = -lastbit;
                end
            end
        end

        
        function [x] = performPseudoternary(~, bits, n, lastbit)
            x = zeros(1, length(bits) * n);
            for i = 1:length(bits)
                if bits(i) == 0
                    x((i-1)*n+1:i*n) = -lastbit;
                    lastbit = -lastbit;
                end
            end
        end
        
        function [x] = performNRZL(~, bits, n)
            x = zeros(1, length(bits) * n);
            for i = 1:length(bits)
                if bits(i) == 1
                    x((i-1)*n+1:i*n) = 1;
                else
                    x((i-1)*n+1:i*n) = -1;
                end
            end
        end
        
        function [x] = performNRZI(~, bits, n, lastbit)
            x = zeros(1, length(bits) * n);
            for i = 1:length(bits)
                if bits(i) == 1
                    lastbit = -lastbit;
                end
                x((i-1)*n+1:i*n) = lastbit;
            end
        end
        
        function [x] = performManchester(~, bits, n)
            x = zeros(1, length(bits) * n);
            for i = 1:length(bits)
                if bits(i) == 1
                    x((i-1)*n+1:(i-1)*n+n/2) = 1;
                    x((i-1)*n+n/2+1:i*n) = -1;
                else
                    x((i-1)*n+1:(i-1)*n+n/2) = -1;
                    x((i-1)*n+n/2+1:i*n) = 1;
                end
            end
        end
        
        function [x] = performDiffManchester(~, bits, n, lastbit)
            x = zeros(1, length(bits) * n);
            for i = 1:length(bits)
                if bits(i) == 1
                    lastbit = -lastbit;
                    x((i-1)*n+1:(i-1)*n+n/2) = -lastbit;
                    x((i-1)*n+n/2+1:i*n) = lastbit;
                else
                    x((i-1)*n+1:(i-1)*n+n/2) = lastbit;
                    x((i-1)*n+n/2+1:i*n) = -lastbit;
                end
            end
        end


        function plotCompareSignal(app, t, x, T, bitrate, plotTitle, VS)
            plot(app.UIAxes_3, t, x, 'b', 'LineWidth', 1.5);
                    ylim(app.UIAxes_3, [-1.3 1.3]);
                    xlim(app.UIAxes_3, [0 T]);
            
                    % Optional: Add grid and label formatting
                    xticks(app.UIAxes_3, 0:1/bitrate:T);
                    grid(app.UIAxes_3, 'on');
                    title(app.UIAxes_3, [plotTitle VS]);
                    xlabel(app.UIAxes_3, 'Time(s)');
                    ylabel(app.UIAxes_3, 'Amplitude');
            
        end
        
        function caseCompareEncoding(app, dropdownValue, t, x, T, bitrate, plotTitle, bits, n, lastbit)
            switch dropdownValue
                        case 'Compare with AMI'
                            generateEncoding(app, t, x, T, bitrate, '')
                            x = performAMI(app, bits, n, lastbit);
                            plotCompareSignal(app, t, x, T, bitrate, plotTitle, 'AMI');
                            
                        case 'Compare with Pseudoternary'
                            generateEncoding(app, t, x, T, bitrate, '')
                            x = performPseudoternary(app, bits, n, lastbit);
                            plotCompareSignal(app, t, x, T, bitrate, plotTitle, 'Pseudoternary');
                            
                        case 'Compare with NRZ-L'
                            generateEncoding(app, t, x, T, bitrate, '')
                            x = performNRZL(app, bits, n);
                            plotCompareSignal(app, t, x, T, bitrate, plotTitle, 'NRZ-L');
                            
                        case 'Compare with NRZ-I'
                            generateEncoding(app, t, x, T, bitrate, '')
                            x = performNRZI(app, bits, n, lastbit);
                            plotCompareSignal(app, t, x, T, bitrate, plotTitle, 'NRZ-I');
                            
                        case 'Compare with Manchester'
                            generateEncoding(app, t, x, T, bitrate, '')
                            x = performManchester(app, bits, n);
                            plotCompareSignal(app, t, x, T, bitrate, plotTitle, 'Manchester');
                            
                        case 'Compare with Differential Manchester'
                            generateEncoding(app, t, x, T, bitrate, '')
                            x = performDiffManchester(app, bits, n, lastbit);
                            plotCompareSignal(app, t, x, T, bitrate, plotTitle, 'Differential Manchester');
   
             end
            
        end
        
        function generateEncoding(app, t, x, T, bitrate, plotTitle)
            % Clear previous plots and plot the new signal
                    cla(app.UIAxes_3);
                    plot(app.UIAxes_3, t, x, 'r', 'LineWidth', 3);
                    ylim(app.UIAxes_3, [-1.3 1.3]);
                    xlim(app.UIAxes_3, [0 T]);
            
                    % Optional: Add grid and label formatting
                    xticks(app.UIAxes_3, 0:1/bitrate:T);
                    grid(app.UIAxes_3, 'on');
                    title(app.UIAxes_3, plotTitle);
                    xlabel(app.UIAxes_3, 'Time (s)');
                    ylabel(app.UIAxes_3, 'Amplitude');
                    hold(app.UIAxes_3, 'on');
            
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the app on screen
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            posX = (screenSize(3) - appWidth) / 2;
            posY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [posX, posY];
            
            % Compare functionality hidden PCM
            app.DeltaLabel_2.Visible = 'off';
            app.EditField_15.Visible = 'off';
            app.CompareButton_2.Visible = 'off';
            
            % Compare functionality hidden DM
            app.FrequencyLabel.Visible = 'off';
            app.EditField_6.Visible = 'off';
            app.SamplingRateLabel.Visible = 'off';
            app.EditField_7.Visible = 'off';
            app.CompareButton.Visible = 'off';


            % Lamp positions on startup
            app.Lamp.Position = [112 704 10 10];
            app.Lamp_2.Position = [41, 679, 10, 10];
            app.Lamp_3.Position = [34, 679, 10, 10];
            
            % UI Axes initial position
            app.OriginalAxesPosition = app.UIAxes.Position;
            
            selectedTab = app.TabGroup_3.SelectedTab;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.IsExpanded
                app.ComparewithPCMButton.Visible = 'on';
                app.UIAxes.Position = app.OriginalAxesPosition;
                app.IsExpanded = false;
                app.Button.Position = [976 577 30 30];
                app.UIAxes.FontSize = 11;
            else
                % Compare functionality hidden
                app.ComparewithPCMButton.Visible = 'off';
                app.FrequencyLabel.Visible = 'off';
                app.EditField_6.Visible = 'off';
                app.SamplingRateLabel.Visible = 'off';
                app.EditField_7.Visible = 'off';
                app.CompareButton.Visible = 'off';
                app.UIAxes.Position = [50 50 980 600];  % New expanded size
                app.IsExpanded = true;
                app.Button.Position = [1000 619 30 30];
                app.UIAxes.FontSize = 16;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.IsExpanded
                app.ComparewithDMButton.Visible = 'on';
                app.UIAxes_2.Position = app.OriginalAxesPosition;
                app.IsExpanded = false;
                app.Button_2.Position = [976 577 30 30];
                app.UIAxes_2.FontSize = 11;
            else
                % Compare functionality hidden
                app.ComparewithDMButton.Visible = 'off';
                app.DeltaLabel_2.Visible = 'off';
                app.EditField_15.Visible = 'off';
                app.CompareButton_2.Visible = 'off';
                app.UIAxes_2.Position = [50 50 980 600];  % New expanded size
                app.IsExpanded = true;
                app.Button_2.Position = [1000 619 30 30];
                app.UIAxes_2.FontSize = 16;
            end
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.XTickLabelMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
            
            
            % Get user inputs
            delta = app.EditField.Value;
            range_min = app.EditField_2.Value;
            range_max = app.EditField_3.Value;
            gap = app.EditField_4.Value;
            amplitude = app.EditField_5.Value;
            func_type = app.DropDown.Value;
               
        
            % Generate time vector
            t = range_min:gap:range_max;
        
            % Generate signal based on selected function
            switch func_type
                case 'sin(x)'
                    x = abs(amplitude * sin(t));
                case 'cos(x)'
                    x = abs(amplitude * cos(t));
            end
        
            % Delta modulation
            x_dm = app.performDeltaModulation(x, delta);
        
            % Plot on UIAxes
            cla(app.UIAxes); % Clear previous plots
            plot(app.UIAxes, t, x, 'r', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            stairs(app.UIAxes, t, x_dm, 'b', 'LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');
            
            % User input for PCM
            freq = app.EditField_6.Value;
            sampRate = app.EditField_7.Value;
            
            % PCM
            q = freq/2^(sampRate-1);
            x0 = fix(x/q);
            y1 = x0*q;
            
            plot(app.UIAxes, t, y1, 'Color', [0 .76 .60], 'LineWidth', 1.5);
            title(app.UIAxes, 'Signal Comparison: Delta vs PCM');
            legend(app.UIAxes, {'Original', 'Delta Modulated', 'PCM'});
            
            hold(app.UIAxes, 'on');
            
            xlim(app.UIAxes, [range_min range_max]);
            ylim(app.UIAxes, [0 amplitude + delta]);  % Adjust as needed
        end

        % Button pushed function: CompareButton_2
        function CompareButton_2Pushed(app, event)
            app.UIAxes_2.XTickMode = 'auto';
            app.UIAxes_2.YTickMode = 'auto';
            app.UIAxes_2.XTickLabelMode = 'auto';
            app.UIAxes_2.YTickLabelMode = 'auto';
            
            
            % Get user inputs
            range_min = app.EditField_9.Value;
            range_max = app.EditField_10.Value;
            gap = app.EditField_11.Value;
            amplitude = app.EditField_12.Value;
            func_type = app.DropDown_2.Value;
               
        
            % Generate time vector
            t = range_min:gap:range_max;
        
            % Generate signal based on selected function
            switch func_type
                case 'sin(x)'
                    x = abs(amplitude * sin(t));
                case 'cos(x)'
                    x = abs(amplitude * cos(t));
            end
        
            % User input for PCM
            freq = app.EditField_13.Value;
            sampRate = app.EditField_14.Value;
            
            % PCM
            q = freq/2^(sampRate-1);
            x0 = fix(x/q);
            y1 = x0*q;
            
        
            % Plot on UIAxes
            cla(app.UIAxes_2); % Clear previous plots
            plot(app.UIAxes_2, t, x, 'r', 'LineWidth', 1.5);
            hold(app.UIAxes_2, 'on');
            
            plot(app.UIAxes_2, t, y1, 'b', 'LineWidth', 1.5);
            hold(app.UIAxes_2, 'on');
            
            % DM
            delta = app.EditField_15.Value;
            
            % Delta modulation
            x_dm = app.performDeltaModulation(x, delta);
            
            
            stairs(app.UIAxes_2, t, x_dm, 'Color', [0 .76 .60], 'LineWidth', 1.5);
            
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            title(app.UIAxes_2, 'Signal Comparison: PCM vs Delta');
            legend(app.UIAxes_2, 'Original', 'PCM', 'Delta Modulated');
            grid(app.UIAxes_2, 'on');
            hold(app.UIAxes_2, 'off');
            
            xlim(app.UIAxes_2, [range_min range_max]);
            ylim(app.UIAxes_2, [0 amplitude + delta]);  % Adjust as needed
        end

        % Button pushed function: ComparewithDMButton
        function ComparewithDMButtonPushed(app, event)
            % Compare functionality hidden PCM
            app.ComparewithDMButton.Visible = 'off';
            app.DeltaLabel_2.Visible = 'on';
            app.EditField_15.Visible = 'on';
            app.CompareButton_2.Visible = 'on';
        end

        % Button pushed function: ComparewithPCMButton
        function ComparewithPCMButtonPushed(app, event)
                app.ComparewithPCMButton.Visible = 'off';
                app.FrequencyLabel.Visible = 'on';
                app.EditField_6.Visible = 'on';
                app.SamplingRateLabel.Visible = 'on';
                app.EditField_7.Visible = 'on';
                app.CompareButton.Visible = 'on';
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.XTickLabelMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
            
            
            % Get user inputs
            delta = app.EditField.Value;
            range_min = app.EditField_2.Value;
            range_max = app.EditField_3.Value;
            gap = app.EditField_4.Value;
            amplitude = app.EditField_5.Value;
            func_type = app.DropDown.Value;
        
            % Generate time vector
            t = range_min:gap:range_max;
        
            % Generate signal based on selected function
            switch func_type
                case 'sin(x)'
                    x = abs(amplitude * sin(t));
                case 'cos(x)'
                    x = abs(amplitude * cos(t));
            end
        
            % Delta modulation
            x_dm = app.performDeltaModulation(x, delta);
        
            % Plot on UIAxes
            cla(app.UIAxes); % Clear previous plots
            plot(app.UIAxes, t, x, 'r', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            stairs(app.UIAxes, t, x_dm, 'b', 'LineWidth', 1.5);
            legend(app.UIAxes, 'Original Signal', 'Delta Modulated Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Delta Modulation');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            
            xlim(app.UIAxes, [range_min range_max]);
            ylim(app.UIAxes, [0 amplitude + delta]);  % Adjust as needed
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            app.UIAxes_2.XTickMode = 'auto';
            app.UIAxes_2.YTickMode = 'auto';
            app.UIAxes_2.XTickLabelMode = 'auto';
            app.UIAxes_2.YTickLabelMode = 'auto';
            
            
            % Get user inputs
            range_min = app.EditField_9.Value;
            range_max = app.EditField_10.Value;
            gap = app.EditField_11.Value;
            amplitude = app.EditField_12.Value;
            func_type = app.DropDown_2.Value;
               
        
            % Generate time vector
            t = range_min:gap:range_max;
        
            % Generate signal based on selected function
            switch func_type
                case 'sin(x)'
                    x = abs(amplitude * sin(t));
                case 'cos(x)'
                    x = abs(amplitude * cos(t));
            end
        
            % User input for PCM
            freq = app.EditField_13.Value;
            sampRate = app.EditField_14.Value;
         
            % PCM
            q = freq/2^(sampRate-1);
            x0 = fix(x/q);
            y1 = x0*q;
            
        
            % Plot on UIAxes
            cla(app.UIAxes_2); % Clear previous plots
            plot(app.UIAxes_2, t, x, 'r', 'LineWidth', 1.5);
            hold(app.UIAxes_2, 'on');
            
            plot(app.UIAxes_2, t, y1, 'b', 'LineWidth', 1.5);
            legend(app.UIAxes_2, 'Original Signal', 'PCM Signal');
            hold(app.UIAxes_2, 'off');
            
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            title(app.UIAxes_2, 'Pulse Code Modulation');
            grid(app.UIAxes_2, 'on');
            
            xlim(app.UIAxes_2, [range_min range_max]);
            ylim(app.UIAxes_2, [0 amplitude]);  % Adjust as needed
        end

        % Button pushed function: GenerateButton_3
        function GenerateButton_3Pushed(app, event)
            bitArray = [app.a1.Value app.a2.Value app.a3.Value app.a4.Value app.a5.Value app.a6.Value app.a7.Value app.a8.Value];
            
            bitrate = app.EditField_19.Value;
            n = app.EditField_20.Value;
            T = length(bitArray)/bitrate;
            N = n * length(bitArray);
            dt = T / N;
            t = 0:dt:T - dt;
            
            lastbit = app.ButtonGroup.SelectedObject.Text;
            lastbit = str2num(lastbit);
            
            selectedTab = app.TabGroup_3.SelectedTab;
            switch selectedTab
                case app.AMITab
                    x = performAMI(app, bitArray, n, lastbit);
                    generateEncoding(app, t, x, T, bitrate, 'AMI Encoding')
                    
                case app.PseudoternaryTab
                    x = performPseudoternary(app, bitArray, n, lastbit);
                    generateEncoding(app, t, x, T, bitrate, 'Pseudoternary Encoding');
                
                case app.NRZLTab
                    x = performNRZL(app, bitArray, n);
                    generateEncoding(app, t, x, T, bitrate, 'NRZ-L Encoding');
                
                case app.NRZITab
                    x = performNRZI(app, bitArray, n, lastbit);
                    generateEncoding(app, t, x, T, bitrate, 'NRZ-I Encoding');
                
                case app.ManchesterTab
                    x = performManchester(app, bitArray, n);
                    generateEncoding(app, t, x, T, bitrate, 'Manchester Encoding');
                
                case app.DifferentialManchesterTab
                    x = performDiffManchester(app, bitArray, n, lastbit);
                    generateEncoding(app, t, x, T, bitrate, 'Differential Manchester Encoding');
                
            end
             
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            % Change blue lamp position
            if selectedTab == app.tab1
                app.Lamp.Position = [112 704 10 10];
        
            elseif selectedTab == app.tab2
                app.Lamp.Position = [189 704 10 10];
            end
        end

        % Selection change function: TabGroup_2
        function TabGroup_2SelectionChanged(app, event)
            selectedTab = app.TabGroup_2.SelectedTab;
            
            % Change blue lamp position
            if selectedTab == app.PCM
                app.Lamp_2.Position = [41, 679, 10, 10];
        
            elseif selectedTab == app.DM
                app.Lamp_2.Position = [87, 679, 10, 10];
            end
        end

        % Selection change function: TabGroup_3
        function TabGroup_3SelectionChanged(app, event)
            selectedTab = app.TabGroup_3.SelectedTab;
     
            if selectedTab == app.AMITab || selectedTab == app.PseudoternaryTab || selectedTab == app.NRZITab || selectedTab == app.DifferentialManchesterTab
                app.LastbitLabel.Visible = 'on';
                app.ButtonGroup.Visible = 'on';
                app.GenerateButton_3.Position = [109 258 208 32];
            else
                app.LastbitLabel.Visible = 'off';
                app.ButtonGroup.Visible = 'off';
                app.GenerateButton_3.Position = [108 314 208 32];
            end
            
            switch selectedTab
                case app.AMITab
                    app.Lamp_3.Position = [34, 679, 10, 10];
                    app.DropDown_3.Items = {'Compare', 'Compare with Pseudoternary', 'Compare with NRZ-L', 'Compare with NRZ-I', 'Compare with Manchester', 'Compare with Differential Manchester'};
                    
                case app.PseudoternaryTab
                    app.Lamp_3.Position = [140, 679, 10, 10];
                    app.DropDown_3.Items = {'Compare', 'Compare with AMI', 'Compare with NRZ-L', 'Compare with NRZ-I', 'Compare with Manchester', 'Compare with Differential Manchester'};
                    
                case app.NRZLTab
                    app.Lamp_3.Position = [203, 679, 10, 10];
                    app.DropDown_3.Items = {'Compare', 'Compare with AMI', 'Compare with Pseudoternary', 'Compare with NRZ-L', 'Compare with NRZ-I', 'Compare with Manchester', 'Compare with Differential Manchester'};
                    
                case app.NRZITab
                    app.Lamp_3.Position = [263, 679, 10, 10];
                    app.DropDown_3.Items = {'Compare', 'Compare with AMI', 'Compare with Pseudoternary', 'Compare with NRZ-L', 'Compare with NRZ-I', 'Compare with Manchester', 'Compare with Differential Manchester'};
                    
                case app.ManchesterTab
                    app.Lamp_3.Position = [354, 679, 10, 10];
                    app.DropDown_3.Items = {'Compare', 'Compare with AMI', 'Compare with Pseudoternary', 'Compare with NRZ-L', 'Compare with NRZ-I', 'Compare with Manchester', 'Compare with Differential Manchester'};
                    
                case app.DifferentialManchesterTab
                    app.Lamp_3.Position = [505, 679, 10, 10];
                    app.DropDown_3.Items = {'Compare', 'Compare with AMI', 'Compare with Pseudoternary', 'Compare with NRZ-L', 'Compare with NRZ-I', 'Compare with Manchester', 'Compare with Differential Manchester'};
           end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disp('App is closing...');
            delete(app);
        end

        % Value changed function: a1
        function a1ValueChanged(app, event)
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.IsExpanded
                app.Panel.Visible = 'on';
                app.DropDown_3.Visible = 'on';
                app.UIAxes_3.Position = app.OriginalAxesPosition;
                app.IsExpanded = false;
                app.Button_3.Position = [976 577 30 30];
                app.UIAxes_3.FontSize = 11;
            else
                % Compare functionality hidden
                app.Panel.Visible = 'off';
                app.DropDown_3.Visible = 'off';
                app.DeltaLabel_2.Visible = 'off';
                app.EditField_15.Visible = 'off';
                app.CompareButton_2.Visible = 'off';
                app.UIAxes_3.Position = [50 30 980 600];  % New expanded size
                app.IsExpanded = true;
                app.Button_3.Position = [1000 599 30 30];
                app.UIAxes_3.FontSize = 16;
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            dropdownValue = app.DropDown_3.Value;
            
            bitArray = [app.a1.Value app.a2.Value app.a3.Value app.a4.Value app.a5.Value app.a6.Value app.a7.Value app.a8.Value];
            
            bitrate = app.EditField_19.Value;
            n = app.EditField_20.Value;
            T = length(bitArray)/bitrate;
            N = n * length(bitArray);
            dt = T / N;
            t = 0:dt:T - dt;
            
            lastbit = app.ButtonGroup.SelectedObject.Text;
            lastbit = str2num(lastbit);
            
            selectedTab = app.TabGroup_3.SelectedTab;
            switch selectedTab
                case app.AMITab
                    x = performAMI(app, bitArray, n, lastbit);
                    caseCompareEncoding(app, dropdownValue, t, x, T, bitrate, 'Signal Comparison: AMI vs ', bitArray, n, lastbit);
                    legend(app.UIAxes_3, 'AMI');
                    
                case app.PseudoternaryTab
                    x = performPseudoternary(app, bitArray, n, lastbit);
                    caseCompareEncoding(app, dropdownValue, t, x, T, bitrate, 'Signal Comparison: Pseudoternary vs ', bitArray, n, lastbit);
                    legend(app.UIAxes_3, 'Pseudoternary');
                
                case app.NRZLTab
                    x = performNRZL(app, bitArray, n);
                    caseCompareEncoding(app, dropdownValue, t, x, T, bitrate, 'Signal Comparison: NRZ-L vs ', bitArray, n, lastbit);
                    legend(app.UIAxes_3, 'NRZ-L');
                    
                case app.NRZITab
                    x = performNRZI(app, bitArray, n, lastbit);
                    caseCompareEncoding(app, dropdownValue, t, x, T, bitrate, 'Signal Comparison: NRZ-I vs ', bitArray, n, lastbit);
                    legend(app.UIAxes_3, 'NRZ-I');
                    
                case app.ManchesterTab
                    x = performManchester(app, bitArray, n);
                    caseCompareEncoding(app, dropdownValue, t, x, T, bitrate, 'Signal Comparison: Manchester vs ', bitArray, n, lastbit);
                    legend(app.UIAxes_3, 'Manchester');
                    
                case app.DifferentialManchesterTab
                    x = performDiffManchester(app, bitArray, n, lastbit);
                    caseCompareEncoding(app, dropdownValue, t, x, T, bitrate, 'Signal Comparison: Differential Manchester vs ', bitArray, n, lastbit);
                    legend(app.UIAxes_3, 'Differential Manchester');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.tab1 = uitab(app.TabGroup);
            app.tab1.Title = 'Signal Conversion';
            app.TabGroup_2 = uitabgroup(app.tab1);
            app.TabGroup_2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_2SelectionChanged, true);
            app.PCM = uitab(app.TabGroup_2);
            app.PCM.Title = 'PCM';
            app.GenerateButton_2 = uibutton(app.PCM, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.SignalRangeLabel_2 = uilabel(app.PCM);
            app.SignalRangeLabel_2.Text = 'Signal Range:';
            app.EditField_9 = uieditfield(app.PCM, 'numeric');
            app.toLabel_2 = uilabel(app.PCM);
            app.toLabel_2.Text = 'to';
            app.EditField_10 = uieditfield(app.PCM, 'numeric');
            app.EditField_10.Value = 10;
            app.RangeGapLabel_2 = uilabel(app.PCM);
            app.RangeGapLabel_2.Text = 'Range Gap:';
            app.EditField_11 = uieditfield(app.PCM, 'numeric');
            app.EditField_11.Limits = [0 Inf];
            app.EditField_11.Value = 0.1;
            app.DropDown_2 = uidropdown(app.PCM);
            app.DropDown_2.Items = {'sin(x)', 'cos(x)'};
            app.DropDown_2.Value = 'sin(x)';
            app.FunctionLabel_2 = uilabel(app.PCM);
            app.FunctionLabel_2.Text = 'Function:';
            app.EditField_12 = uieditfield(app.PCM, 'numeric');
            app.EditField_12.Value = 5;
            app.AmplitudeLabel_2 = uilabel(app.PCM);
            app.AmplitudeLabel_2.Text = 'Amplitude:';
            app.PulseCodeModulationPCMLabel = uilabel(app.PCM);
            app.PulseCodeModulationPCMLabel.Text = 'Pulse Code Modulation (PCM)';
            app.ComparewithDMButton = uibutton(app.PCM, 'push');
            app.ComparewithDMButton.ButtonPushedFcn = createCallbackFcn(app, @ComparewithDMButtonPushed, true);
            app.ComparewithDMButton.Text = 'Compare with DM';
            app.CompareButton_2 = uibutton(app.PCM, 'push');
            app.CompareButton_2.ButtonPushedFcn = createCallbackFcn(app, @CompareButton_2Pushed, true);
            app.CompareButton_2.Text = 'Compare';
            app.FrequencyLabel_2 = uilabel(app.PCM);
            app.FrequencyLabel_2.Text = 'Frequency:';
            app.EditField_13 = uieditfield(app.PCM, 'numeric');
            app.EditField_13.Limits = [0 Inf];
            app.EditField_13.Value = 6;
            app.SamplingRateLabel_2 = uilabel(app.PCM);
            app.SamplingRateLabel_2.Text = 'Sampling Rate:';
            app.EditField_14 = uieditfield(app.PCM, 'numeric');
            app.EditField_14.Limits = [0 Inf];
            app.EditField_14.Value = 3;
            app.DeltaLabel_2 = uilabel(app.PCM);
            app.DeltaLabel_2.Text = 'Delta:';
            app.EditField_15 = uieditfield(app.PCM, 'numeric');
            app.EditField_15.Limits = [0 Inf];
            app.EditField_15.Value = 0.2;
            app.UIAxes_2 = uiaxes(app.PCM);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XLim = [0 1];
            app.UIAxes_2.XLimMode = 'manual';
            app.UIAxes_2.YLim = [0 1];
            app.UIAxes_2.YLimMode = 'manual';
            app.UIAxes_2.CLim = [0 1];
            app.UIAxes_2.CLimMode = 'manual';
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Button_2 = uibutton(app.PCM, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'icon-expand.png';
            app.Button_2.Text = '';
            app.DM = uitab(app.TabGroup_2);
            app.DM.Title = 'DM';
            app.DeltaModulationDMLabel = uilabel(app.DM);
            app.DeltaModulationDMLabel.Text = 'Delta Modulation (DM)';
            app.DeltaLabel = uilabel(app.DM);
            app.DeltaLabel.Text = 'Delta:';
            app.EditField = uieditfield(app.DM, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 0.2;
            app.SignalRangeLabel = uilabel(app.DM);
            app.SignalRangeLabel.Text = 'Signal Range:';
            app.EditField_2 = uieditfield(app.DM, 'numeric');
            app.toLabel = uilabel(app.DM);
            app.toLabel.Text = 'to';
            app.EditField_3 = uieditfield(app.DM, 'numeric');
            app.EditField_3.Value = 10;
            app.RangeGapLabel = uilabel(app.DM);
            app.RangeGapLabel.Text = 'Range Gap:';
            app.EditField_4 = uieditfield(app.DM, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.Value = 0.1;
            app.DropDown = uidropdown(app.DM);
            app.DropDown.Items = {'sin(x)', 'cos(x)'};
            app.DropDown.Value = 'sin(x)';
            app.FunctionLabel = uilabel(app.DM);
            app.FunctionLabel.Text = 'Function:';
            app.GenerateButton = uibutton(app.DM, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.EditField_5 = uieditfield(app.DM, 'numeric');
            app.EditField_5.Value = 5;
            app.AmplitudeLabel = uilabel(app.DM);
            app.AmplitudeLabel.Text = 'Amplitude:';
            app.UIAxes = uiaxes(app.DM);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.CLim = [0 1];
            app.UIAxes.CLimMode = 'manual';
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Button = uibutton(app.DM, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icon-expand.png';
            app.Button.Text = '';
            app.ComparewithPCMButton = uibutton(app.DM, 'push');
            app.ComparewithPCMButton.ButtonPushedFcn = createCallbackFcn(app, @ComparewithPCMButtonPushed, true);
            app.ComparewithPCMButton.Text = 'Compare with PCM';
            app.FrequencyLabel = uilabel(app.DM);
            app.FrequencyLabel.Text = 'Frequency:';
            app.EditField_6 = uieditfield(app.DM, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.Value = 6;
            app.SamplingRateLabel = uilabel(app.DM);
            app.SamplingRateLabel.Text = 'Sampling Rate:';
            app.EditField_7 = uieditfield(app.DM, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.Value = 3;
            app.CompareButton = uibutton(app.DM, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.Lamp_2 = uilamp(app.tab1);
            app.tab2 = uitab(app.TabGroup);
            app.tab2.Title = 'Encoding';
            app.TabGroup_3 = uitabgroup(app.tab2);
            app.TabGroup_3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_3SelectionChanged, true);
            app.AMITab = uitab(app.TabGroup_3);
            app.AMITab.Title = 'AMI';
            app.AlternateMarkInversionAMILabel = uilabel(app.AMITab);
            app.AlternateMarkInversionAMILabel.Text = 'Alternate Mark Inversion (AMI)';
            app.PseudoternaryTab = uitab(app.TabGroup_3);
            app.PseudoternaryTab.Title = 'Pseudoternary';
            app.PseudoternaryLabel = uilabel(app.PseudoternaryTab);
            app.PseudoternaryLabel.Text = 'Pseudoternary';
            app.NRZLTab = uitab(app.TabGroup_3);
            app.NRZLTab.Title = 'NRZ-L';
            app.NRZLLabel = uilabel(app.NRZLTab);
            app.NRZLLabel.Text = 'NRZ-L';
            app.NRZITab = uitab(app.TabGroup_3);
            app.NRZITab.Title = 'NRZ-I';
            app.NRZILabel = uilabel(app.NRZITab);
            app.NRZILabel.Text = 'NRZ-I';
            app.ManchesterTab = uitab(app.TabGroup_3);
            app.ManchesterTab.Title = 'Manchester';
            app.ManchesterLabel = uilabel(app.ManchesterTab);
            app.ManchesterLabel.Text = 'Manchester';
            app.DifferentialManchesterTab = uitab(app.TabGroup_3);
            app.DifferentialManchesterTab.Title = 'Differential Manchester';
            app.DifferentialManchesterLabel = uilabel(app.DifferentialManchesterTab);
            app.DifferentialManchesterLabel.Text = 'Differential Manchester';
            app.Panel = uipanel(app.tab2);
            app.GenerateButton_3 = uibutton(app.Panel, 'push');
            app.GenerateButton_3.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_3Pushed, true);
            app.GenerateButton_3.Text = 'Generate';
            app.BitsLabel = uilabel(app.Panel);
            app.BitsLabel.Text = 'Bits:';
            app.a1 = uieditfield(app.Panel, 'numeric');
            app.a1.ValueChangedFcn = createCallbackFcn(app, @a1ValueChanged, true);
            app.a1.Limits = [0 Inf];
            app.a1.ValueDisplayFormat = '%.0f';
            app.BitrateLabel = uilabel(app.Panel);
            app.BitrateLabel.Text = 'Bitrate:';
            app.EditField_19 = uieditfield(app.Panel, 'numeric');
            app.EditField_19.Limits = [0 Inf];
            app.EditField_19.ValueDisplayFormat = '%.0f';
            app.EditField_19.Value = 1;
            app.EditField_20 = uieditfield(app.Panel, 'numeric');
            app.EditField_20.Limits = [0 Inf];
            app.EditField_20.ValueDisplayFormat = '%.0f';
            app.EditField_20.Value = 1000;
            app.SamplingRateLabel_3 = uilabel(app.Panel);
            app.SamplingRateLabel_3.Text = 'Sampling Rate:';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '1';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '-1';
            app.LastbitLabel = uilabel(app.Panel);
            app.LastbitLabel.Text = 'Lastbit:';
            app.a2 = uieditfield(app.Panel, 'numeric');
            app.a2.Limits = [0 Inf];
            app.a2.ValueDisplayFormat = '%.0f';
            app.a3 = uieditfield(app.Panel, 'numeric');
            app.a3.Limits = [0 Inf];
            app.a3.ValueDisplayFormat = '%.0f';
            app.a4 = uieditfield(app.Panel, 'numeric');
            app.a4.Limits = [0 Inf];
            app.a4.ValueDisplayFormat = '%.0f';
            app.a5 = uieditfield(app.Panel, 'numeric');
            app.a5.Limits = [0 Inf];
            app.a5.ValueDisplayFormat = '%.0f';
            app.a6 = uieditfield(app.Panel, 'numeric');
            app.a6.Limits = [0 Inf];
            app.a6.ValueDisplayFormat = '%.0f';
            app.a7 = uieditfield(app.Panel, 'numeric');
            app.a7.Limits = [0 Inf];
            app.a7.ValueDisplayFormat = '%.0f';
            app.a8 = uieditfield(app.Panel, 'numeric');
            app.a8.Limits = [0 Inf];
            app.a8.ValueDisplayFormat = '%.0f';
            app.DropDown_3 = uidropdown(app.tab2);
            app.DropDown_3.Items = {'Compare', 'Compare with Pseudoternary', 'Compare with NRZ-L', 'Compare with NRZ-I', 'Compare with Manchester', 'Compare with Differential Manchester'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'Compare';
            app.UIAxes_3 = uiaxes(app.tab2);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_3.XLim = [0 1];
            app.UIAxes_3.XLimMode = 'manual';
            app.UIAxes_3.YLim = [0 1];
            app.UIAxes_3.YLimMode = 'manual';
            app.UIAxes_3.CLim = [0 1];
            app.UIAxes_3.CLimMode = 'manual';
            app.UIAxes_3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_3.YTick = [-1 0 1];
            app.UIAxes_3.YTickLabel = {'-1'; '0'; '1'};
            app.Button_3 = uibutton(app.tab2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'icon-expand.png';
            app.Button_3.Text = '';
            app.Lamp_3 = uilamp(app.tab2);
            app.Lamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainMenu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end