classdef SplashScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        SignalConversionandEncodingDecodingSchemeVisualizerLabel  matlab.ui.control.Label
        LoadingLabel  matlab.ui.control.Label
        ProgressFill  matlab.ui.container.Panel
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            posX = (screenSize(3) - appWidth) / 2;
            posY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [posX, posY];
            
            disp('Splashscreen running...');
            
            totalDuration = 2;  % Total loading time
            steps = 100;
            pauseDuration = totalDuration / steps;
        
            fullWidth = 853;  % Final width of the progress bar
        
            for i = 1:steps
                % Set progress bar width
                app.ProgressFill.Position(3) = fullWidth * (i / steps);
        
                % Force update text
                app.LoadingLabel.Text = sprintf('Loading... %d%%', i);
        
                % Force reflow if needed (this often helps)
                app.LoadingLabel.Visible = 'off';
                drawnow limitrate;
                app.LoadingLabel.Visible = 'on';
        
                pause(pauseDuration);
            end
            
            % Launch the next app and close splash
            MainMenu;
            disp('Splashscreen is closing...');
            delete(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disp('Splashscreen is closing...');
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SignalConversionandEncodingDecodingSchemeVisualizerLabel = uilabel(app.UIFigure);
            app.SignalConversionandEncodingDecodingSchemeVisualizerLabel.Text = {'Signal Conversion and Encoding/Decoding'; 'Scheme Visualizer'};
            app.LoadingLabel = uilabel(app.UIFigure);
            app.LoadingLabel.Text = 'Loading... ';
            app.ProgressFill = uipanel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SplashScreen

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end